classdef derivatives < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EnteraFunctiontoderiveLabel  matlab.ui.control.Label
        Input                        matlab.ui.control.EditField
        Derive                       matlab.ui.control.Button
        ResultLabel                  matlab.ui.control.Label
        Output                       matlab.ui.control.EditField
        DeriveresecttoLabel          matlab.ui.control.Label
        Variable                     matlab.ui.control.EditField
        DegreeLabel                  matlab.ui.control.Label
        Degree                       matlab.ui.control.EditField
        DerivetoexactvalueLabel      matlab.ui.control.Label
        Value                        matlab.ui.control.EditField
        OutputExact                  matlab.ui.control.EditField
        GeneralLabel                 matlab.ui.control.Label
        ExactLabel                   matlab.ui.control.Label
        ExitButton                   matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Derive
        function DeriveButtonPushed(app, event)
            app.Output.Value = string("");
            app.OutputExact.Value = string("");
            
            func = sym(app.Input.Value);
            var = sym(app.Variable.Value);
            deg = eval(app.Degree.Value);
%             val = eval(app.Value.Value);
            
            generalResult = diff(func,var,deg);
            app.Output.Value = string(generalResult);
            
            if (app.Value.Value ~= "")
                val = eval(app.Value.Value);
                exactResult = vpa(subs(generalResult,val));
                app.OutputExact.Value = string(exactResult);
            end
        end

        % Button pushed function: ExitButton
        function ExitPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnteraFunctiontoderiveLabel = uilabel(app.UIFigure);
            app.EnteraFunctiontoderiveLabel.Text = 'Enter a Function to derive: ';
            app.Input = uieditfield(app.UIFigure, 'text');
            app.Derive = uibutton(app.UIFigure, 'push');
            app.Derive.ButtonPushedFcn = createCallbackFcn(app, @DeriveButtonPushed, true);
            app.Derive.Text = 'Derive';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result: ';
            app.Output = uieditfield(app.UIFigure, 'text');
            app.DeriveresecttoLabel = uilabel(app.UIFigure);
            app.DeriveresecttoLabel.Text = 'Derive resect to: ';
            app.Variable = uieditfield(app.UIFigure, 'text');
            app.Variable.Value = 't';
            app.DegreeLabel = uilabel(app.UIFigure);
            app.DegreeLabel.Text = 'Degree: ';
            app.Degree = uieditfield(app.UIFigure, 'text');
            app.Degree.Value = '1';
            app.DerivetoexactvalueLabel = uilabel(app.UIFigure);
            app.DerivetoexactvalueLabel.Text = 'Derive to exact value:';
            app.Value = uieditfield(app.UIFigure, 'text');
            app.OutputExact = uieditfield(app.UIFigure, 'text');
            app.GeneralLabel = uilabel(app.UIFigure);
            app.GeneralLabel.Text = 'General:';
            app.ExactLabel = uilabel(app.UIFigure);
            app.ExactLabel.Text = 'Exact:';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = derivatives

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end