classdef integrals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Input                           matlab.ui.control.EditField
        EnterafunctiontointegrateLabel  matlab.ui.control.Label
        Output                          matlab.ui.control.EditField
        TheResultLabel                  matlab.ui.control.Label
        StateSwitchLabel                matlab.ui.control.Label
        StateSwitch                     matlab.ui.control.Switch
        From                            matlab.ui.control.EditField
        To                              matlab.ui.control.EditField
        Variable                        matlab.ui.control.EditField
        FromLabel                       matlab.ui.control.Label
        ToLabel                         matlab.ui.control.Label
        VariabletoIntegrateLabel        matlab.ui.control.Label
        IntegrateButton                 matlab.ui.control.Button
        Exit                            matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: StateSwitch
        function SwitchChanged(app, event)
%             value = app.StateSwitch.Value;
            if strcmp(app.StateSwitch.Value,'Unbounded')
                app.From.Enable = 'off';
                app.To.Enable = 'off';
%                 app.Variable.Enable = 'off';
            else
                app.From.Enable = 'on';
                app.To.Enable = 'on';
%                 app.Variable.Enable = 'on';
                
            end
        end

        % Button pushed function: IntegrateButton
        function IntegratePushed(app, event)
            if strcmp(app.StateSwitch.Value,'Unbounded')
                func = str2sym(app.Input.Value);
                var = str2sym(app.Variable.Value);
                res = int(func,var);
%                 app.Output.Text = string(res);
%                 set(app.Output,'String',x);
                app.Output.Value = string(res);
            else
                func = inline(app.Input.Value,app.Variable.Value);
                a = eval(app.From.Value);
                b = eval(app.To.Value);
                res = quad(func,a,b);
                app.Output.Value = string(res);
            end
        end

        % Button pushed function: Exit
        function ExitButtonPused(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Input = uieditfield(app.UIFigure, 'text');
            app.EnterafunctiontointegrateLabel = uilabel(app.UIFigure);
            app.EnterafunctiontointegrateLabel.Text = 'Enter a function to integrate:';
            app.Output = uieditfield(app.UIFigure, 'text');
            app.TheResultLabel = uilabel(app.UIFigure);
            app.TheResultLabel.Text = 'The Result: ';
            app.StateSwitchLabel = uilabel(app.UIFigure);
            app.StateSwitchLabel.Text = 'State';
            app.StateSwitch = uiswitch(app.UIFigure, 'slider');
            app.StateSwitch.Items = {'Unbounded', 'Bounded'};
            app.StateSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchChanged, true);
            app.StateSwitch.Value = 'Unbounded';
            app.From = uieditfield(app.UIFigure, 'text');
            app.From.Enable = 'off';
            app.To = uieditfield(app.UIFigure, 'text');
            app.To.Enable = 'off';
            app.Variable = uieditfield(app.UIFigure, 'text');
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From:';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.VariabletoIntegrateLabel = uilabel(app.UIFigure);
            app.VariabletoIntegrateLabel.Text = {'Variable'; 'to Integrate:'};
            app.IntegrateButton = uibutton(app.UIFigure, 'push');
            app.IntegrateButton.ButtonPushedFcn = createCallbackFcn(app, @IntegratePushed, true);
            app.IntegrateButton.Text = 'Integrate';
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPused, true);
            app.Exit.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = integrals

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end