classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        yourfunctionplotLabel         matlab.ui.control.Label
        ScaledfunctionLabel           matlab.ui.control.Label
        writeyourfunctionhereftLabel  matlab.ui.control.Label
        ftEditFieldLabel              matlab.ui.control.Label
        ftEditField                   matlab.ui.control.EditField
        ChangetheseparametettomaketimescaleandshifLabel  matlab.ui.control.Label
        ParameterofcfatbButtonGroup   matlab.ui.container.ButtonGroup
        aLabel                        matlab.ui.control.Label
        bLabel                        matlab.ui.control.Label
        cLabel                        matlab.ui.control.Label
        tiincjLabel                   matlab.ui.control.Label
        b                             matlab.ui.control.NumericEditField
        a                             matlab.ui.control.NumericEditField
        c                             matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Interval                      matlab.ui.control.EditField
        PlotButton                    matlab.ui.control.Button
        PresstoconvolveftwithhtLabel  matlab.ui.control.Label
        ConvolutionButton             matlab.ui.control.Button
        htLabel                       matlab.ui.control.Label
        htEditField                   matlab.ui.control.EditField
        Defualtrectt2Label            matlab.ui.control.Label
        GoToTransformationsWindowButton  matlab.ui.control.Button
        ExtraDropDownLabel            matlab.ui.control.Label
        ExtraDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        func % Description
        
        func_interval % Description
    end

    methods (Access = private)
    
        function [y,ny] = my_conv(app,x,nx,h,nh,increment)
            %UNTITLED Summary of this function goes here
            %   Detailed explanation goes here
            nyStart = nx(1) + nh(1);
            nyEnd = nx(length(nx)) + nh(length(nh));
            ny = [nyStart:increment:nyEnd];
            y = conv(x,h);
         end


    end


    methods (Access = private)

        % Value changed function: a
        function aValueChanged(app, event)
            value = app.a.Value;
            
        end

        % Value changed function: b
        function bValueChanged(app, event)
            value = app.b.Value;
            
        end

        % Value changed function: c
        function cValueChanged(app, event)
            value = app.c.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
%             display(app.ftEditField.Value)
            f = inline(app.ftEditField.Value,'t')
            app.func = f;
            aa = app.a.Value;
            bb = app.b.Value;
            cc = app.c.Value;
            x =  app.Interval.Value;
            interval = eval(x);
            app.func_interval = interval;
            try
              y = cc .* f(aa.*interval + bb);
            plot(app.UIAxes,interval,y);
            catch exception
              app.ftEditField.BackgroundColor = 'red';
            end


%             h = inline('hardlim(t+1) - hardlim(t-1)','t');
%             y_conv = conv(h(interval),f(interval));
%              plot(app.UIAxes2,z,y_conv)
            
        end

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            f =inline(app.ftEditField.Value,'t');
             t_f = eval(app.Interval.Value);
              interval = split(app.Interval.Value,":");
              increment = str2double(interval(2));
               if(app.htEditField.Value == "")
                    h = inline('hardlim(t+1) - hardlim(t-1)','t');
                    t_h = -1:increment:1;
               else
                   h = inline(app.htEditField.Value,'t');
                   t_h = t_f;
               end
             try
                [y ny] = my_conv(app,f(t_f),t_f,h(t_h),t_h,increment);
                plot(app.UIAxes2,ny,y)
             catch exception
                 app.ftEditField.BackgroundColor = 'red';
             end
%             (t>0) & t<2   3.*(t>0 & t<1)
        end

        % Value changed function: ftEditField
        function ftEditFieldValueChanged(app, event)


        end

        % Value changing function: ftEditField
        function ftEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
             app.ftEditField.BackgroundColor = 'white';
             
        end

        % Button pushed function: GoToTransformationsWindowButton
        function GoToTransformationsWindowButtonPushed(app, event)
            transformations
            delete(app)
            
        end

        % Value changed function: ExtraDropDown
        function ExtraOptionValueChanged(app, event)
            value = app.ExtraDropDown.Value;
            if value == '0'
                
            elseif value == '1'
                transformations
                delete(app)
            elseif value == '2'
                 integrals
                 delete(app)
            elseif value == '3'
                derivatives
                delete(app)
            elseif value == '4'
                solvequations
                delete(app)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.yourfunctionplotLabel = uilabel(app.UIFigure);
            app.yourfunctionplotLabel.Text = 'your function plot ';
            app.ScaledfunctionLabel = uilabel(app.UIFigure);
            app.ScaledfunctionLabel.Text = 'Scaled function ';
            app.writeyourfunctionhereftLabel = uilabel(app.UIFigure);
            app.writeyourfunctionhereftLabel.Text = 'write your function here f(t)';
            app.ftEditFieldLabel = uilabel(app.UIFigure);
            app.ftEditFieldLabel.Text = 'f(t)';
            app.ftEditField = uieditfield(app.UIFigure, 'text');
            app.ftEditField.ValueChangedFcn = createCallbackFcn(app, @ftEditFieldValueChanged, true);
            app.ftEditField.ValueChangingFcn = createCallbackFcn(app, @ftEditFieldValueChanging, true);
            app.ChangetheseparametettomaketimescaleandshifLabel = uilabel(app.UIFigure);
            app.ChangetheseparametettomaketimescaleandshifLabel.Text = 'Change these parametet to make time scale and shif';
            app.ParameterofcfatbButtonGroup = uibuttongroup(app.UIFigure);
            app.ParameterofcfatbButtonGroup.Title = 'Parameter of cf(at+b)';
            app.aLabel = uilabel(app.ParameterofcfatbButtonGroup);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.ParameterofcfatbButtonGroup);
            app.bLabel.Text = 'b';
            app.cLabel = uilabel(app.ParameterofcfatbButtonGroup);
            app.cLabel.Text = 'c';
            app.tiincjLabel = uilabel(app.ParameterofcfatbButtonGroup);
            app.tiincjLabel.Text = 't=i:inc:j';
            app.b = uieditfield(app.ParameterofcfatbButtonGroup, 'numeric');
            app.b.ValueChangedFcn = createCallbackFcn(app, @bValueChanged, true);
            app.a = uieditfield(app.ParameterofcfatbButtonGroup, 'numeric');
            app.a.ValueChangedFcn = createCallbackFcn(app, @aValueChanged, true);
            app.a.Value = 1;
            app.c = uieditfield(app.ParameterofcfatbButtonGroup, 'numeric');
            app.c.ValueChangedFcn = createCallbackFcn(app, @cValueChanged, true);
            app.c.Value = 1;
            app.Label = uilabel(app.ParameterofcfatbButtonGroup);
            app.Label.Text = '';
            app.Interval = uieditfield(app.ParameterofcfatbButtonGroup, 'text');
            app.Interval.Value = '-1:0.1:1';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PresstoconvolveftwithhtLabel = uilabel(app.UIFigure);
            app.PresstoconvolveftwithhtLabel.Text = 'Press to convolve f(t) with h(t)';
            app.ConvolutionButton = uibutton(app.UIFigure, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Convolution';
            app.htLabel = uilabel(app.UIFigure);
            app.htLabel.Text = 'h(t)';
            app.htEditField = uieditfield(app.UIFigure, 'text');
            app.Defualtrectt2Label = uilabel(app.UIFigure);
            app.Defualtrectt2Label.Text = 'Defualt rect(t/2)';
            app.GoToTransformationsWindowButton = uibutton(app.UIFigure, 'push');
            app.GoToTransformationsWindowButton.ButtonPushedFcn = createCallbackFcn(app, @GoToTransformationsWindowButtonPushed, true);
            app.GoToTransformationsWindowButton.Text = 'Go To Transformations Window';
            app.ExtraDropDownLabel = uilabel(app.UIFigure);
            app.ExtraDropDownLabel.Text = 'Extra: ';
            app.ExtraDropDown = uidropdown(app.UIFigure);
            app.ExtraDropDown.Items = {'None', 'Transforms', 'Integrals', 'Derivatives', 'Solve Equations'};
            app.ExtraDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.ExtraDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtraOptionValueChanged, true);
            app.ExtraDropDown.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end