classdef solvequations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EnteranequationtosolveLabel  matlab.ui.control.Label
        Input                        matlab.ui.control.EditField
        SolveButton                  matlab.ui.control.Button
        VariabletosolveforLabel      matlab.ui.control.Label
        Variable                     matlab.ui.control.EditField
        ResultLabel                  matlab.ui.control.Label
        ExitButton                   matlab.ui.control.Button
        Output                       matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.Output.Value = "";
            equ = sym(app.Input.Value);
            var = sym(app.Variable.Value);
            ans = solve(equ,var);
            length =size(ans);
            output_res = "Solutions: " + newline;
            for i = 1:length(1)
                output_res = output_res + newline + string(ans(i));
            end
%             length = l(0);
            app.Output.Value = output_res;
        end

        % Button pushed function: ExitButton
        function ExitPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnteranequationtosolveLabel = uilabel(app.UIFigure);
            app.EnteranequationtosolveLabel.Text = 'Enter an equation to solve:';
            app.Input = uieditfield(app.UIFigure, 'text');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VariabletosolveforLabel = uilabel(app.UIFigure);
            app.VariabletosolveforLabel.Text = 'Variable to solve for: ';
            app.Variable = uieditfield(app.UIFigure, 'text');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Output = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = solvequations

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end