classdef transformations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GoBackButton                  matlab.ui.control.Button
        writeyourfunctionhereftLabel  matlab.ui.control.Label
        ftEditFieldLabel              matlab.ui.control.Label
        ftEditField                   matlab.ui.control.EditField
        TransformDropDownLabel        matlab.ui.control.Label
        TransformDropDown             matlab.ui.control.DropDown
        FLabel                        matlab.ui.control.Label
        result                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
             
            project
            delete( app);
           
        end

        % Value changed function: TransformDropDown
        function TransformDropDownValueChanged(app, event)
            
            value = app.TransformDropDown.Value;
            syms t x n
            f =app.ftEditField.Value;
            if value == '0'
                app.FLabel.Text = 'F(ω)'
                x=fourier(eval(f))
                app.result.Text=string(x)
            elseif value == '1'
                app.FLabel.Text = 'F(S)'
                x=laplace(eval(f))
                app.result.Text=string(x)
            elseif value == '2'
                app.FLabel.Text = 'X[Z]'
                 x=ztrans(eval(f))
                app.result.Text=string(x)
                
            end
        display(value)
            
        end

        % Value changed function: ftEditField
        function ftEditFieldValueChanged(app, event)
            value = app.ftEditField.Value;
            TransformDropDownValueChanged(app,event)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.writeyourfunctionhereftLabel = uilabel(app.UIFigure);
            app.writeyourfunctionhereftLabel.Text = 'write your function here f(t)';
            app.ftEditFieldLabel = uilabel(app.UIFigure);
            app.ftEditFieldLabel.Text = 'f(t)';
            app.ftEditField = uieditfield(app.UIFigure, 'text');
            app.ftEditField.ValueChangedFcn = createCallbackFcn(app, @ftEditFieldValueChanged, true);
            app.TransformDropDownLabel = uilabel(app.UIFigure);
            app.TransformDropDownLabel.Text = 'Transform';
            app.TransformDropDown = uidropdown(app.UIFigure);
            app.TransformDropDown.Items = {'Fouriere', 'Laplace', 'Z'};
            app.TransformDropDown.ItemsData = {'0', '1', '2'};
            app.TransformDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformDropDownValueChanged, true);
            app.TransformDropDown.Value = '0';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F(ω)';
            app.result = uilabel(app.UIFigure);
            app.result.Text = 'The Result';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transformations

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end